classdef InterfaceGrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PressioneosBotesabaixoparagerarossonsdasNotasLabel  matlab.ui.control.Label
        SiB3Button               matlab.ui.control.Button
        LA3Button                matlab.ui.control.Button
        SolG3Button              matlab.ui.control.Button
        FF3Button                matlab.ui.control.Button
        MiE3Button               matlab.ui.control.Button
        RD3Button                matlab.ui.control.Button
        DuraodaNotaSpinner       matlab.ui.control.Spinner
        DuraodaNotaSpinnerLabel  matlab.ui.control.Label
        VolumeSpinner            matlab.ui.control.Spinner
        VolumeSpinnerLabel       matlab.ui.control.Label
        DC3Button                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DC3Button
        function DC3ButtonPushed(app, event)
            playNote(app, 262);  % Frequência de Dó (C3) na terceira oitava

            function playNote(app, frequency)

                fs = 48000;  % Frequência de amostragem
            
                % Recuperar a duração e o volume a partir dos Spinners
                duration = app.DuraodaNotaSpinner.Value;
                volume = app.VolumeSpinner.Value;
            
                % Calcular o número de amostras
                T = floor(fs / frequency);
                N = duration * fs;
            
                % Gerar o sinal inicial
                x = 2 * rand(T, 1) - 1;
            
                % Inicializar a saída
                y = zeros(N, 1);
                y(1:T) = x;
            
                % Aplicar o filtro de Karplus-Strong
                for i = T+1:N
                    y(i) = 0.995 * (y(i-T) + y(i-T+1)) / 2;
                end
            
                % Ajustar o volume
                y = y * volume;
            
                % Tocar a nota
                sound(y, fs);
            end

        end

        % Value changed function: DuraodaNotaSpinner
        function DuraodaNotaSpinnerValueChanged(app, event)
            duration = app.DuraodaNotaSpinner.Value;
            
        end

        % Value changed function: VolumeSpinner
        function VolumeSpinnerValueChanged(app, event)
            volume = app.VolumeSpinner.Value;
            
        end

        % Button pushed function: RD3Button
        function RD3ButtonPushed(app, event)
            playNote(app, 294);  % Frequência de Dó (C3) na terceira oitava

            function playNote(app, frequency)

                fs = 48000;  % Frequência de amostragem
            
                % Recuperar a duração e o volume a partir dos Spinners
                duration = app.DuraodaNotaSpinner.Value;
                volume = app.VolumeSpinner.Value;
            
                % Calcular o número de amostras
                T = floor(fs / frequency);
                N = duration * fs;
            
                % Gerar o sinal inicial
                x = 2 * rand(T, 1) - 1;
            
                % Inicializar a saída
                y = zeros(N, 1);
                y(1:T) = x;
            
                % Aplicar o filtro de Karplus-Strong
                for i = T+1:N
                    y(i) = 0.995 * (y(i-T) + y(i-T+1)) / 2;
                end
            
                % Ajustar o volume
                y = y * volume;
            
                % Tocar a nota
                sound(y, fs);
            end

        end

        % Button pushed function: MiE3Button
        function MiE3ButtonPushed(app, event)
            playNote(app, 330);  % Frequência de Dó (C3) na terceira oitava

            function playNote(app, frequency)

                fs = 48000;  % Frequência de amostragem
            
                % Recuperar a duração e o volume a partir dos Spinners
                duration = app.DuraodaNotaSpinner.Value;
                volume = app.VolumeSpinner.Value;
            
                % Calcular o número de amostras
                T = floor(fs / frequency);
                N = duration * fs;
            
                % Gerar o sinal inicial
                x = 2 * rand(T, 1) - 1;
            
                % Inicializar a saída
                y = zeros(N, 1);
                y(1:T) = x;
            
                % Aplicar o filtro de Karplus-Strong
                for i = T+1:N
                    y(i) = 0.995 * (y(i-T) + y(i-T+1)) / 2;
                end
            
                % Ajustar o volume
                y = y * volume;
            
                % Tocar a nota
                sound(y, fs);
            end

        end

        % Button pushed function: FF3Button
        function FF3ButtonPushed(app, event)
            playNote(app, 350);  % Frequência de Dó (C3) na terceira oitava

            function playNote(app, frequency)

                fs = 48000;  % Frequência de amostragem
            
                % Recuperar a duração e o volume a partir dos Spinners
                duration = app.DuraodaNotaSpinner.Value;
                volume = app.VolumeSpinner.Value;
            
                % Calcular o número de amostras
                T = floor(fs / frequency);
                N = duration * fs;
            
                % Gerar o sinal inicial
                x = 2 * rand(T, 1) - 1;
            
                % Inicializar a saída
                y = zeros(N, 1);
                y(1:T) = x;
            
                % Aplicar o filtro de Karplus-Strong
                for i = T+1:N
                    y(i) = 0.995 * (y(i-T) + y(i-T+1)) / 2;
                end
            
                % Ajustar o volume
                y = y * volume;
            
                % Tocar a nota
                sound(y, fs);
            end

        end

        % Button pushed function: SolG3Button
        function SolG3ButtonPushed(app, event)
            playNote(app, 392);  % Frequência de Dó (C3) na terceira oitava

            function playNote(app, frequency)

                fs = 48000;  % Frequência de amostragem
            
                % Recuperar a duração e o volume a partir dos Spinners
                duration = app.DuraodaNotaSpinner.Value;
                volume = app.VolumeSpinner.Value;
            
                % Calcular o número de amostras
                T = floor(fs / frequency);
                N = duration * fs;
            
                % Gerar o sinal inicial
                x = 2 * rand(T, 1) - 1;
            
                % Inicializar a saída
                y = zeros(N, 1);
                y(1:T) = x;
            
                % Aplicar o filtro de Karplus-Strong
                for i = T+1:N
                    y(i) = 0.995 * (y(i-T) + y(i-T+1)) / 2;
                end
            
                % Ajustar o volume
                y = y * volume;
            
                % Tocar a nota
                sound(y, fs);
            end

        end

        % Button pushed function: LA3Button
        function LA3ButtonPushed(app, event)
            playNote(app, 440);  % Frequência de Dó (C3) na terceira oitava

            function playNote(app, frequency)

                fs = 48000;  % Frequência de amostragem
            
                % Recuperar a duração e o volume a partir dos Spinners
                duration = app.DuraodaNotaSpinner.Value;
                volume = app.VolumeSpinner.Value;
            
                % Calcular o número de amostras
                T = floor(fs / frequency);
                N = duration * fs;
            
                % Gerar o sinal inicial
                x = 2 * rand(T, 1) - 1;
            
                % Inicializar a saída
                y = zeros(N, 1);
                y(1:T) = x;
            
                % Aplicar o filtro de Karplus-Strong
                for i = T+1:N
                    y(i) = 0.995 * (y(i-T) + y(i-T+1)) / 2;
                end
            
                % Ajustar o volume
                y = y * volume;
            
                % Tocar a nota
                sound(y, fs);
            end

        end

        % Button pushed function: SiB3Button
        function SiB3ButtonPushed(app, event)
            playNote(app, 494);  % Frequência de Dó (C3) na terceira oitava

            function playNote(app, frequency)

                fs = 48000;  % Frequência de amostragem
            
                % Recuperar a duração e o volume a partir dos Spinners
                duration = app.DuraodaNotaSpinner.Value;
                volume = app.VolumeSpinner.Value;
            
                % Calcular o número de amostras
                T = floor(fs / frequency);
                N = duration * fs;
            
                % Gerar o sinal inicial
                x = 2 * rand(T, 1) - 1;
            
                % Inicializar a saída
                y = zeros(N, 1);
                y(1:T) = x;
            
                % Aplicar o filtro de Karplus-Strong
                for i = T+1:N
                    y(i) = 0.995 * (y(i-T) + y(i-T+1)) / 2;
                end
            
                % Ajustar o volume
                y = y * volume;
            
                % Tocar a nota
                sound(y, fs);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DC3Button = uibutton(app.UIFigure, 'push');
            app.DC3Button.ButtonPushedFcn = createCallbackFcn(app, @DC3ButtonPushed, true);
            app.DC3Button.Text = 'Dó (C3)';
            app.VolumeSpinnerLabel = uilabel(app.UIFigure);
            app.VolumeSpinnerLabel.Text = 'Volume';
            app.VolumeSpinner = uispinner(app.UIFigure);
            app.VolumeSpinner.Step = 0.1;
            app.VolumeSpinner.Limits = [0 Inf];
            app.VolumeSpinner.ValueChangedFcn = createCallbackFcn(app, @VolumeSpinnerValueChanged, true);
            app.DuraodaNotaSpinnerLabel = uilabel(app.UIFigure);
            app.DuraodaNotaSpinnerLabel.Text = 'Duração da Nota';
            app.DuraodaNotaSpinner = uispinner(app.UIFigure);
            app.DuraodaNotaSpinner.Step = 0.1;
            app.DuraodaNotaSpinner.Limits = [0 Inf];
            app.DuraodaNotaSpinner.ValueChangedFcn = createCallbackFcn(app, @DuraodaNotaSpinnerValueChanged, true);
            app.RD3Button = uibutton(app.UIFigure, 'push');
            app.RD3Button.ButtonPushedFcn = createCallbackFcn(app, @RD3ButtonPushed, true);
            app.RD3Button.Text = 'Ré (D3)';
            app.MiE3Button = uibutton(app.UIFigure, 'push');
            app.MiE3Button.ButtonPushedFcn = createCallbackFcn(app, @MiE3ButtonPushed, true);
            app.MiE3Button.Text = 'Mi (E3)';
            app.FF3Button = uibutton(app.UIFigure, 'push');
            app.FF3Button.ButtonPushedFcn = createCallbackFcn(app, @FF3ButtonPushed, true);
            app.FF3Button.Text = 'Fá (F3)';
            app.SolG3Button = uibutton(app.UIFigure, 'push');
            app.SolG3Button.ButtonPushedFcn = createCallbackFcn(app, @SolG3ButtonPushed, true);
            app.SolG3Button.Text = 'Sol (G3)';
            app.LA3Button = uibutton(app.UIFigure, 'push');
            app.LA3Button.ButtonPushedFcn = createCallbackFcn(app, @LA3ButtonPushed, true);
            app.LA3Button.Text = 'Lá (A3)';
            app.SiB3Button = uibutton(app.UIFigure, 'push');
            app.SiB3Button.ButtonPushedFcn = createCallbackFcn(app, @SiB3ButtonPushed, true);
            app.SiB3Button.Text = 'Si (B3)';
            app.PressioneosBotesabaixoparagerarossonsdasNotasLabel = uilabel(app.UIFigure);
            app.PressioneosBotesabaixoparagerarossonsdasNotasLabel.Text = 'Pressione os Botões abaixo para gerar os sons das Notas na Escala Diatônica de Dó maior na 3ª oitava';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceGrafica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end